classdef DSBDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        DetectEdgesButton  matlab.ui.control.Button
        BlackWhite         matlab.ui.control.Button
        UploadImageButton  matlab.ui.control.Button
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)

            %a can be access from all functions
            global image1;
            [filename, pathname] = uigetfile('*.*', 'Pick a MATLAB code file');
            %concat to get full name of file
            filename=strcat(pathname,filename);
            
            %plot in UI1
            image1=imread(filename);
            imshow(image1,'Parent',app.UIAxes);
        end

        % Button pushed function: BlackWhite
        function BlackWhitePushed(app, event)
            %find edges in image1 (processed already) & change to binary
            global image1;
            global B;
            B = image1;
            B = im2gray(B);
            %edges = edge(BW,'canny');
            
            %imshow(BW);
            %compute hough transform using binary img 
            %[H,T,R] = hough(BW);
            %imshow(H,[], )
            %plot in UI2
            imshow(B,'Parent',app.UIAxes2);
        end

        % Button pushed function: DetectEdgesButton
        function DetectEdgesButtonPushed(app, event)
            global image1;
            global B;
            global C;
            C=B;
            C = edge(C,'canny');
            imshow(C,'Parent',app.UIAxes3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'BW Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Edge Detect')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.BlackWhite = uibutton(app.UIFigure, 'push');
            app.BlackWhite.ButtonPushedFcn = createCallbackFcn(app, @BlackWhitePushed, true);
            app.BlackWhite.Text = 'BW';
            app.DetectEdgesButton = uibutton(app.UIFigure, 'push');
            app.DetectEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectEdgesButtonPushed, true);
            app.DetectEdgesButton.Text = 'Detect Edges';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSBDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end